classdef elevatorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        MethodButtonGroup     matlab.ui.container.ButtonGroup
        BackwardButton        matlab.ui.control.ToggleButton
        ForwardButton         matlab.ui.control.ToggleButton
        CentralButton         matlab.ui.control.ToggleButton
        StepSizeSpinnerLabel  matlab.ui.control.Label
        StepSizeSpinner       matlab.ui.control.Spinner
        ResetButton           matlab.ui.control.Button
        QuantityListBox       matlab.ui.control.ListBox
        QuantityListBoxLabel  matlab.ui.control.Label
        LOADButton            matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LOADButton
        function LOADButtonPushed(app, event)
             %% This Callback Function runs when the app user presses the button Load on the app and it is used to plot the graph based on users inputs
             %% First to assign the recieved values to a variable

            selectedButton = app.MethodButtonGroup.SelectedObject;    %% This statement recieves the value from the Button Group for the numerical differentiation method that is going to be used
            method = selectedButton.Text;                             %% This converts the input into text format
            valueToBeFound = char(app.QuantityListBox.Value);         %%Similarly this statement gets the value from the List Box for the Quantity that is to be shown in the graph
            stepSize = app.StepSizeSpinner.Value;                     %% This assigns the input from the user for the desired step size from the spinner

            %% This is the main Function that we use to get the final computed data after taking inputs from the previous step
            % Inputs:
            % valueToBeFound: 'Altitude', 'Velocity', or 'Acceleration'
            % method: 'Forward', 'Backward', or 'Central'
            % stepSize: step interval for data plotting

            [timeData,altitude,velocity,acceleration] = numericalDiffFunction(valueToBeFound,method,stepSize);

           %% Nested Consitional Statements is used to plot the graph based on the inputs given by the user 
           % If the the Quantity the User wants is altitude then only this conditional section runs
             if strcmpi(valueToBeFound,'Altitude')

                plot(app.UIAxes, timeData(1:stepSize:end), altitude(1:stepSize:end), '*-c', 'LineWidth', 1)     %% The data number is made sured to be aligned based on the users required step size
                title(app.UIAxes,"Original Data",'Color', 'white')                                              %% This updates the title of the graph
                xlabel(app.UIAxes,"Time (seconds)")                                                             %% This updates the name of the x axis of the graph
                ylabel(app.UIAxes,"Altitude")                                                                   %% This updates the name of the y axis of the graph

           % If the the Quantity the User wants is acceleration then only this conditional section runs
             elseif strcmpi(valueToBeFound,'Acceleration')

                  if strcmpi(method, "Forward")                                                             %% if the numerical differentiation method's input from the user is Forward then this condtion runs and plots the acceleration graph for forward difference method
                      plot(app.UIAxes, timeData(1:stepSize:end-1,1), acceleration, '*-b', 'LineWidth', 1)       %% this plots acceleration vs time graph; also time values match the velocity's calculation times, excluding the final one since forward differencing isn’t possible there.
                      title(app.UIAxes,"Forward Method",'Color', 'white')                                       %% This updates the title of the graph
                  
                  elseif strcmpi(method, "Backward")                                                        %% if the numerical differentiation method's input from the user is Backward then this condtion runs and plots the acceleration graph for backward difference method
                         plot(app.UIAxes, timeData(2:stepSize:end,1), acceleration, '*-c', 'LineWidth', 1)      %% this plots acceleration vs time graph; also time values match the velocity's calculation times, excluding the last one since backward differencing isn’t possible there.
                         title(app.UIAxes,"Backward Difference Method",'Color', 'white')                        %% This updates the title of the graph

                  else                                                                                      %% if the numerical differentiation method's input from the user is Central then this condtion runs and plots the acceleration graph for central difference method
                      plot(app.UIAxes, timeData(2:stepSize:end-1,1), acceleration, '*-w', 'LineWidth', 1)       %% this plots acceleration vs time graph; also time values match the velocity's calculation times, excluding the first and last one since central differencing isn’t possible there.
                      title(app.UIAxes,"Central Difference Method",'Color', 'white')                            %% This updates the title of the graph
                  end
                       xlabel(app.UIAxes,"Time (seconds)")         %% This updates the name of the x axis of the graph
                       ylabel(app.UIAxes,"Acceleration")           %% This updates the name of the y axis of the graph
             
            % If the the Quantity the User wants is Velocity then only this conditional section runs
              else 
                    if strcmpi(method, "Forward")                                                         %% if the numerical differentiation method's input from the user is Forward then this condtion runs and plots the velocity graph for forward difference method
                       plot(app.UIAxes, timeData(1:stepSize:end-1,1), velocity, '*-b', 'LineWidth', 1)          %% this plots velocity vs time graph; also time values match the velocity's calculation times, excluding the final one since forward differencing isn’t possible there.
                       title(app.UIAxes,"Forward Difference Method",'Color', 'white')                           %% This updates the title of the graph
                    elseif strcmpi(method, "Backward")                                                    %% if the numerical differentiation method's input from the user is Backward then this condtion runs and plots the velocity graph for Backward difference method
                           plot(app.UIAxes, timeData(2:stepSize:end), velocity, '*-c', 'LineWidth', 1)          %% this plots velocity vs time graph; also time values match the velocity's calculation times, excluding the first one since backward differencing isn’t possible there.
                           title(app.UIAxes,"Backward Difference Method",'Color', 'white')                      %% This updates the title of the graph
                    else                                                                                  %% if the numerical differentiation method's input from the user is Central then this condtion runs and plots the velocity graph for Central difference method
                            plot(app.UIAxes, timeData(2:stepSize:end-1), velocity, '*-w', 'LineWidth', 1)       %% this plots velocity vs time graph; also time values match the velocity's calculation times, excluding the first and last one since central differencing isn’t possible there.
                            title(app.UIAxes,"Central Difference Method",'Color', 'white')                      %% This updates the title of the graph
                     end
                            xlabel(app.UIAxes,"Time (seconds)")      %% This updates the name of the x axis of the graph
                            ylabel(app.UIAxes,"Velocity (m/s)")      %% This updates the name of the y axis of the graph
        
              end

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
           %% This callback Funtion runs when the user presses the Reset button and is used to reset everthing on the app
           
            app.MethodButtonGroup.SelectedObject = app.CentralButton; %% this resets the Method Button Group's Button to the Central Button
            app.QuantityListBox.Value = 'Acceleration'; %% this resets the Quantity List Box to Acceleration
            cla(app.UIAxes) %% clears the graph
            title(app.UIAxes,"Title",'Color', 'white') %% Resets the title to original title
            xlabel(app.UIAxes,"X") %% This resets the name of the x axis of the graph
            ylabel(app.UIAxes,"Y") %% This resets the name of the y axis of the graph
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LOADButton = uibutton(app.UIFigure, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Text = 'LOAD';
            app.QuantityListBoxLabel = uilabel(app.UIFigure);
            app.QuantityListBoxLabel.Text = 'Quantity';
            app.QuantityListBox = uilistbox(app.UIFigure);
            app.QuantityListBox.Items = {'Acceleration', 'Velocity', 'Altitude'};
            app.QuantityListBox.Value = 'Acceleration';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.StepSizeSpinner = uispinner(app.UIFigure);
            app.StepSizeSpinner.Limits = [1 5];
            app.StepSizeSpinner.Value = 1;
            app.StepSizeSpinnerLabel = uilabel(app.UIFigure);
            app.StepSizeSpinnerLabel.Text = 'Step Size';
            app.MethodButtonGroup = uibuttongroup(app.UIFigure);
            app.MethodButtonGroup.Title = 'Method';
            app.CentralButton = uitogglebutton(app.MethodButtonGroup);
            app.CentralButton.Text = 'Central';
            app.CentralButton.Value = true;
            app.ForwardButton = uitogglebutton(app.MethodButtonGroup);
            app.ForwardButton.Text = 'Forward';
            app.BackwardButton = uitogglebutton(app.MethodButtonGroup);
            app.BackwardButton.Text = 'Backward';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = elevatorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end